classdef demoapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        PlotButton               matlab.ui.control.Button
        StepSizeEditField        matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel   matlab.ui.control.Label
        FinalTimeEditField       matlab.ui.control.NumericEditField
        FinalTimeEditFieldLabel  matlab.ui.control.Label
        PlottypeDropDown         matlab.ui.control.DropDown
        PlottypeDropDownLabel    matlab.ui.control.Label
        Z0EditField              matlab.ui.control.NumericEditField
        Z0EditFieldLabel         matlab.ui.control.Label
        Y0EditField              matlab.ui.control.NumericEditField
        Y0EditFieldLabel         matlab.ui.control.Label
        X0EditField              matlab.ui.control.NumericEditField
        X0EditFieldLabel         matlab.ui.control.Label
        BetaEditField            matlab.ui.control.NumericEditField
        BetaEditFieldLabel       matlab.ui.control.Label
        RhoEditField             matlab.ui.control.NumericEditField
        RhoEditFieldLabel        matlab.ui.control.Label
        SigmaEditField           matlab.ui.control.NumericEditField
        SigmaEditFieldLabel      matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            sigma=app.SigmaEditField.Value;
            beta=app.BetaEditField.Value;
            rho=app.RhoEditField.Value;

            f = @(t, x, y, z) sigma * (y - x);
            g = @(t, x, y, z) x * (rho - z) - y;
            r = @(t, x, y, z) x * y - beta * z;

            t0=0;
            x0=app.X0EditField.Value;
            y0=app.Y0EditField.Value;
            z0=app.Z0EditField.Value;
            tn=app.FinalTimeEditField.Value;
            h=app.StepSizeEditField.Value;
            n=(tn-t0)/h;
            t(1)=t0;
            x(1)=x0;
            y(1)=y0;
            z(1)=z0;


            for i = 1:n
                t(i+1) = t0 + i*h;

                k1 = h*f(t(i), x(i), y(i), z(i));
                m1 = h*g(t(i), x(i), y(i), z(i));
                l1 = h*r(t(i), x(i), y(i), z(i));

                k2 = h*f(t(i) + h/2, x(i) + k1/2, y(i) + m1/2, z(i) + l1/2);
                m2 = h*g(t(i) + h/2, x(i) + k1/2, y(i) + m1/2, z(i) + l1/2);
                l2 = h*r(t(i) + h/2, x(i) + k1/2, y(i) + m1/2, z(i) + l1/2);

                k3 = h*f(t(i) + h/2, x(i) + k2/2, y(i) + m2/2, z(i) + l2/2);
                m3 = h*g(t(i) + h/2, x(i) + k2/2, y(i) + m2/2, z(i) + l2/2);
                l3 = h*r(t(i) + h/2, x(i) + k2/2, y(i) + m2/2, z(i) + l2/2);

                k4 = h*f(t(i) + h, x(i) + k3, y(i) + m3, z(i) + l3);
                m4 = h*g(t(i) + h, x(i) + k3, y(i) + m3, z(i) + l3);
                l4 = h*r(t(i) + h, x(i) + k3, y(i) + m3, z(i) + l3);

                x(i+1) = x(i) + (1/6)*(k1 + 2*k2 + 2*k3 + k4);
                y(i+1) = y(i) + (1/6)*(m1 + 2*m2 + 2*m3 + m4);
                z(i+1) = z(i) + (1/6)*(l1 + 2*l2 + 2*l3 + l4);
            end
            xlabel(app.UIAxes,'x');
            ylabel(app.UIAxes,'y');
            zlabel(app.UIAxes,'z');
            title(app.UIAxes,'x vs y vs z');
            plot3(app.UIAxes,x,y,z,'r');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Lorentz Attractor')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SigmaEditFieldLabel = uilabel(app.UIFigure);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.UIFigure, 'numeric');
            app.RhoEditFieldLabel = uilabel(app.UIFigure);
            app.RhoEditFieldLabel.Text = 'Rho';
            app.RhoEditField = uieditfield(app.UIFigure, 'numeric');
            app.BetaEditFieldLabel = uilabel(app.UIFigure);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.X0EditFieldLabel = uilabel(app.UIFigure);
            app.X0EditFieldLabel.Text = 'X(0)';
            app.X0EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y0EditFieldLabel = uilabel(app.UIFigure);
            app.Y0EditFieldLabel.Text = 'Y(0)';
            app.Y0EditField = uieditfield(app.UIFigure, 'numeric');
            app.Z0EditFieldLabel = uilabel(app.UIFigure);
            app.Z0EditFieldLabel.Text = 'Z(0)';
            app.Z0EditField = uieditfield(app.UIFigure, 'numeric');
            app.PlottypeDropDownLabel = uilabel(app.UIFigure);
            app.PlottypeDropDownLabel.Text = 'Plot type';
            app.PlottypeDropDown = uidropdown(app.UIFigure);
            app.PlottypeDropDown.Items = {'x vs t', 'y vs t', 'z vs t', 'z vs x', 'y vs x', 'z vs y vs x', 'real time'};
            app.PlottypeDropDown.Value = 'x vs t';
            app.FinalTimeEditFieldLabel = uilabel(app.UIFigure);
            app.FinalTimeEditFieldLabel.Text = 'Final Time';
            app.FinalTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepSizeEditFieldLabel = uilabel(app.UIFigure);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demoapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end