classdef demoapp2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        StaticTab                  matlab.ui.container.Tab
        InvalidInputLabel          matlab.ui.control.Label
        StepSizeEditField          matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel     matlab.ui.control.Label
        FinalTimeEditField         matlab.ui.control.NumericEditField
        FinalTimeEditFieldLabel    matlab.ui.control.Label
        PlottypeDropDown           matlab.ui.control.DropDown
        Z0EditField                matlab.ui.control.NumericEditField
        Z0EditFieldLabel           matlab.ui.control.Label
        PlottypeDropDownLabel      matlab.ui.control.Label
        Y0EditField                matlab.ui.control.NumericEditField
        Y0EditFieldLabel           matlab.ui.control.Label
        X0EditField                matlab.ui.control.NumericEditField
        X0EditFieldLabel           matlab.ui.control.Label
        BetaEditField              matlab.ui.control.NumericEditField
        BetaEditFieldLabel         matlab.ui.control.Label
        RhoEditField               matlab.ui.control.NumericEditField
        RhoEditFieldLabel          matlab.ui.control.Label
        SigmaEditField             matlab.ui.control.NumericEditField
        SigmaEditFieldLabel        matlab.ui.control.Label
        PlotButton                 matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        RealTimeTab                matlab.ui.container.Tab
        RefreshratetoosmallItmustbelessthanitr2Label  matlab.ui.control.Label
        InvalidInputLabel_2        matlab.ui.control.Label
        RefreshRateEditField       matlab.ui.control.NumericEditField
        RefreshRateEditFieldLabel  matlab.ui.control.Label
        DOptionsDropDown           matlab.ui.control.DropDown
        DOptionsDropDownLabel      matlab.ui.control.Label
        SimulateButton             matlab.ui.control.Button
        FinalTimeEditField_2       matlab.ui.control.NumericEditField
        FinalTimeEditField_2Label  matlab.ui.control.Label
        StepSizeEditField_2        matlab.ui.control.NumericEditField
        StepSizeEditField_2Label   matlab.ui.control.Label
        Z0EditField_2              matlab.ui.control.NumericEditField
        Z0EditField_2Label         matlab.ui.control.Label
        Y0EditField_2              matlab.ui.control.NumericEditField
        Y0Label                    matlab.ui.control.Label
        X0EditField_2              matlab.ui.control.NumericEditField
        X0Label                    matlab.ui.control.Label
        BetaEditField_2            matlab.ui.control.NumericEditField
        BetaEditField_2Label       matlab.ui.control.Label
        RhoEditField_2             matlab.ui.control.NumericEditField
        RhoEditField_2Label        matlab.ui.control.Label
        SigmaEditField_2           matlab.ui.control.NumericEditField
        SigmaEditField_2Label      matlab.ui.control.Label
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.InvalidInputLabel.Visible="off";

            sigma=app.SigmaEditField.Value;
            beta=app.BetaEditField.Value;
            rho=app.RhoEditField.Value;

            f=@(t,x,y,z) sigma*(y-x);
            g=@(t,x,y,z) x*(rho-z)-y;
            r=@(t,x,y,z) x*y-beta*z;

            t0=0;
            x0=app.X0EditField.Value;
            y0=app.Y0EditField.Value;
            z0=app.Z0EditField.Value;
            tn=app.FinalTimeEditField.Value;
            h=app.StepSizeEditField.Value;
            n=(tn-t0)/h;

            if x0==0
                x0=10^-50;
            end
            if y0==0
                y0=10^-50;
            end
            if z0==0
                z0=10^-50;
            end

            t(1)=t0;
            x(1)=x0;
            y(1)=y0;
            z(1)=z0;

            if tn<=0  || h<=0 || h>=tn
                app.InvalidInputLabel.Visible="on";
                return;
            end

            for i = 1:n
                t(i+1) = t0 + i*h;

                k1=h*f(t(i),x(i),y(i),z(i));
                m1=h*g(t(i),x(i),y(i),z(i));
                l1=h*r(t(i),x(i),y(i),z(i));

                k2=h*f(t(i)+h/2,x(i)+k1/2,y(i)+m1/2,z(i)+l1/2);
                m2=h*g(t(i)+h/2,x(i)+k1/2,y(i)+m1/2,z(i)+l1/2);
                l2=h*r(t(i)+h/2,x(i)+k1/2,y(i)+m1/2,z(i)+l1/2);

                k3=h*f(t(i)+h/2,x(i)+k2/2,y(i)+m2/2,z(i)+l2/2);
                m3=h*g(t(i)+h/2,x(i)+k2/2,y(i)+m2/2,z(i)+l2/2);
                l3=h*r(t(i)+h/2,x(i)+k2/2,y(i)+m2/2,z(i)+l2/2);

                k4=h*f(t(i)+h,x(i)+k3,y(i)+m3,z(i)+l3);
                m4=h*g(t(i)+h,x(i)+k3,y(i)+m3,z(i)+l3);
                l4=h*r(t(i)+h,x(i)+k3,y(i)+m3,z(i) + l3);

                x(i+1)=x(i)+(1/6)*(k1+2*k2+2*k3+k4);
                y(i+1)=y(i)+(1/6)*(m1+2*m2+2*m3+m4);
                z(i+1)=z(i)+(1/6)*(l1+2*l2+2*l3+l4);
            end

            switch app.PlottypeDropDown.Value

                case 'x vs t'
                    xlabel(app.UIAxes,'t');
                    ylabel(app.UIAxes,'x');
                    title(app.UIAxes,'x vs t');
                    plot(app.UIAxes,t,x);
                    view(app.UIAxes,0,90);

                case 'y vs t'
                    xlabel(app.UIAxes,'t');
                    ylabel(app.UIAxes,'y');
                    title(app.UIAxes,'y vs t');
                    plot(app.UIAxes,t,y);
                    view(app.UIAxes,0,90);

                case 'z vs t'
                    xlabel(app.UIAxes,'t');
                    ylabel(app.UIAxes,'z');
                    title(app.UIAxes,'z vs t');
                    plot(app.UIAxes,t,z);
                    view(app.UIAxes,0,90);

                case 'z vs x'
                    xlabel(app.UIAxes,'x');
                    ylabel(app.UIAxes,'z');
                    title(app.UIAxes,'z vs x');
                    plot(app.UIAxes,x,z,'green');
                    view(app.UIAxes,0,90);

                case 'z vs y'
                    xlabel(app.UIAxes,'y');
                    ylabel(app.UIAxes,'z');
                    title(app.UIAxes,'z vs y');
                    plot(app.UIAxes,y,z,'blue');
                    view(app.UIAxes,0,90);

                case 'y vs x'
                    xlabel(app.UIAxes,'x');
                    ylabel(app.UIAxes,'y');
                    title(app.UIAxes,'y vs x');
                    plot(app.UIAxes,x,y,'red');
                    view(app.UIAxes,0,90);

                case 'z vs y vs x'
                    xlabel(app.UIAxes,'x');
                    ylabel(app.UIAxes,'y');
                    zlabel(app.UIAxes,'z');
                    title(app.UIAxes,'z vs y vs x');
                    plot3(app.UIAxes,x,y,z);
            end


        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            app.InvalidInputLabel_2.Visible="off";
            app.RefreshratetoosmallItmustbelessthanitr2Label.Visible="off";
            
            sigma=app.SigmaEditField_2.Value;
            rho=app.RhoEditField_2.Value;
            beta=app.BetaEditField_2.Value;

            f=@(t,x,y,z) sigma*(y-x);
            g=@(t,x,y,z) x*(rho-z)-y;
            r=@(t,x,y,z) x*y-beta*z;

            t0=0;          
            x0=app.X0EditField_2.Value;         
            y0=app.Y0EditField_2.Value;         
            z0=app.Z0EditField_2.Value;         
            tn=app.FinalTimeEditField_2.Value;         
            h=app.StepSizeEditField_2.Value;         
            n=(tn-t0)/h;

            if x0==0
                x0=10^-50;
            end
            if y0==0
                y0=10^-50;
            end
            if z0==0
                z0=10^-50;
            end

            x(1)=x0;
            y(1)=y0;
            z(1)=z0;
            t(1)=t0;

            refreshRate = app.RefreshRateEditField.Value;

            if tn<=0 || refreshRate<=0 || h<=0 || h>=tn
                app.InvalidInputLabel_2.Visible="on";
                return;
            end
            if refreshRate>=ceil(n/2)
                app.RefreshratetoosmallItmustbelessthanitr2Label.Visible="on";
                return;
            end

            for i = 1:n
                t(i+1) = t0 + i*h;

                k1=h*f(t(i),x(i),y(i),z(i));
                m1=h*g(t(i),x(i),y(i),z(i));
                l1=h*r(t(i),x(i),y(i),z(i));

                k2=h*f(t(i)+h/2,x(i)+k1/2,y(i)+m1/2,z(i)+l1/2);
                m2=h*g(t(i)+h/2,x(i)+k1/2,y(i)+m1/2,z(i)+l1/2);
                l2=h*r(t(i)+h/2,x(i)+k1/2,y(i)+m1/2,z(i)+l1/2);

                k3=h*f(t(i)+h/2,x(i)+k2/2,y(i)+m2/2,z(i)+l2/2);
                m3=h*g(t(i)+h/2,x(i)+k2/2,y(i)+m2/2,z(i)+l2/2);
                l3=h*r(t(i)+h/2,x(i)+k2/2,y(i)+m2/2,z(i)+l2/2);

                k4=h*f(t(i)+h,x(i)+k3,y(i)+m3,z(i)+l3);
                m4=h*g(t(i)+h,x(i)+k3,y(i)+m3,z(i)+l3);
                l4=h*r(t(i)+h,x(i)+k3,y(i)+m3,z(i)+l3);

                x(i+1)=x(i)+(1/6)*(k1+2*k2+2*k3+k4);
                y(i+1)=y(i)+(1/6)*(m1+2*m2+2*m3+m4);
                z(i+1)=z(i)+(1/6)*(l1+2*l2 +2*l3+l4);

                if mod(i, refreshRate)==0||i==n
                    switch app.DOptionsDropDown.Value
                        case 'x vs t'
                            xlabel(app.UIAxes2,'t');
                            ylabel(app.UIAxes2,'x');
                            plot(app.UIAxes2,t(1:i+1),x(1:i+1),'red');

                        case 'y vs t'
                            xlabel(app.UIAxes2,'t');
                            ylabel(app.UIAxes2,'y');
                            plot(app.UIAxes2,t(1:i+1),y(1:i+1),'green');

                        case 'z vs t'
                            xlabel(app.UIAxes2,'t');
                            ylabel(app.UIAxes2,'z');
                            plot(app.UIAxes2,t(1:i+1),z(1:i+1),'blue');

                        case 'y vs x'
                            xlabel(app.UIAxes2,'x');
                            ylabel(app.UIAxes2,'y');
                            plot(app.UIAxes2,x(1:i+1),y(1:i+1),'green');

                        case 'z vs x'
                            xlabel(app.UIAxes2,'x');
                            ylabel(app.UIAxes2,'z');
                            plot(app.UIAxes2,x(1:i+1),z(1:i+1),'red');
                            
                        case 'z vs y'
                            xlabel(app.UIAxes2,'y');
                            ylabel(app.UIAxes2,'z');
                            plot(app.UIAxes2,y(1:i+1),z(1:i+1),'cyan');
                    end
   
                    % Plot x vs y vs z
                    xlabel(app.UIAxes3,'x');
                    ylabel(app.UIAxes3,'y');
                    zlabel(app.UIAxes3,'z');
                    plot3(app.UIAxes3,x(1:i+1), y(1:i+1), z(1:i+1), 'b');
                    drawnow;
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StaticTab = uitab(app.TabGroup);
            app.StaticTab.Title = 'Static';
            app.UIAxes = uiaxes(app.StaticTab);
            title(app.UIAxes, 'Lorentz Attractor')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlotButton = uibutton(app.StaticTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SigmaEditFieldLabel = uilabel(app.StaticTab);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.StaticTab, 'numeric');
            app.RhoEditFieldLabel = uilabel(app.StaticTab);
            app.RhoEditFieldLabel.Text = 'Rho';
            app.RhoEditField = uieditfield(app.StaticTab, 'numeric');
            app.BetaEditFieldLabel = uilabel(app.StaticTab);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.StaticTab, 'numeric');
            app.X0EditFieldLabel = uilabel(app.StaticTab);
            app.X0EditFieldLabel.Text = 'X(0)';
            app.X0EditField = uieditfield(app.StaticTab, 'numeric');
            app.Y0EditFieldLabel = uilabel(app.StaticTab);
            app.Y0EditFieldLabel.Text = 'Y(0)';
            app.Y0EditField = uieditfield(app.StaticTab, 'numeric');
            app.PlottypeDropDownLabel = uilabel(app.StaticTab);
            app.PlottypeDropDownLabel.Text = 'Plot type';
            app.Z0EditFieldLabel = uilabel(app.StaticTab);
            app.Z0EditFieldLabel.Text = 'Z(0)';
            app.Z0EditField = uieditfield(app.StaticTab, 'numeric');
            app.PlottypeDropDown = uidropdown(app.StaticTab);
            app.PlottypeDropDown.Items = {'x vs t', 'y vs t', 'z vs t', 'z vs x', 'z vs y', 'y vs x', 'z vs y vs x'};
            app.PlottypeDropDown.Value = 'x vs t';
            app.FinalTimeEditFieldLabel = uilabel(app.StaticTab);
            app.FinalTimeEditFieldLabel.Text = 'Final Time';
            app.FinalTimeEditField = uieditfield(app.StaticTab, 'numeric');
            app.StepSizeEditFieldLabel = uilabel(app.StaticTab);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.StaticTab, 'numeric');
            app.InvalidInputLabel = uilabel(app.StaticTab);
            app.InvalidInputLabel.Visible = 'off';
            app.InvalidInputLabel.Text = 'Invalid Input';
            app.RealTimeTab = uitab(app.TabGroup);
            app.RealTimeTab.Title = 'RealTime';
            app.UIAxes2 = uiaxes(app.RealTimeTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RealTimeTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.SigmaEditField_2Label = uilabel(app.RealTimeTab);
            app.SigmaEditField_2Label.Text = 'Sigma';
            app.SigmaEditField_2 = uieditfield(app.RealTimeTab, 'numeric');
            app.RhoEditField_2Label = uilabel(app.RealTimeTab);
            app.RhoEditField_2Label.Text = 'Rho';
            app.RhoEditField_2 = uieditfield(app.RealTimeTab, 'numeric');
            app.BetaEditField_2Label = uilabel(app.RealTimeTab);
            app.BetaEditField_2Label.Text = 'Beta';
            app.BetaEditField_2 = uieditfield(app.RealTimeTab, 'numeric');
            app.X0Label = uilabel(app.RealTimeTab);
            app.X0Label.Text = 'X(0)';
            app.X0EditField_2 = uieditfield(app.RealTimeTab, 'numeric');
            app.Y0Label = uilabel(app.RealTimeTab);
            app.Y0Label.Text = 'Y(0)';
            app.Y0EditField_2 = uieditfield(app.RealTimeTab, 'numeric');
            app.Z0EditField_2Label = uilabel(app.RealTimeTab);
            app.Z0EditField_2Label.Text = 'Z(0)';
            app.Z0EditField_2 = uieditfield(app.RealTimeTab, 'numeric');
            app.StepSizeEditField_2Label = uilabel(app.RealTimeTab);
            app.StepSizeEditField_2Label.Text = 'Step Size';
            app.StepSizeEditField_2 = uieditfield(app.RealTimeTab, 'numeric');
            app.FinalTimeEditField_2Label = uilabel(app.RealTimeTab);
            app.FinalTimeEditField_2Label.Text = 'Final Time';
            app.FinalTimeEditField_2 = uieditfield(app.RealTimeTab, 'numeric');
            app.SimulateButton = uibutton(app.RealTimeTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.DOptionsDropDownLabel = uilabel(app.RealTimeTab);
            app.DOptionsDropDownLabel.Text = '2D Options';
            app.DOptionsDropDown = uidropdown(app.RealTimeTab);
            app.DOptionsDropDown.Items = {'x vs t', 'y vs t', 'z vs t', 'y vs x', 'z vs x', 'z vs y'};
            app.DOptionsDropDown.Value = 'x vs t';
            app.RefreshRateEditFieldLabel = uilabel(app.RealTimeTab);
            app.RefreshRateEditFieldLabel.Text = 'Refresh Rate';
            app.RefreshRateEditField = uieditfield(app.RealTimeTab, 'numeric');
            app.InvalidInputLabel_2 = uilabel(app.RealTimeTab);
            app.InvalidInputLabel_2.Visible = 'off';
            app.InvalidInputLabel_2.Text = 'Invalid Input';
            app.RefreshratetoosmallItmustbelessthanitr2Label = uilabel(app.RealTimeTab);
            app.RefreshratetoosmallItmustbelessthanitr2Label.Visible = 'off';
            app.RefreshratetoosmallItmustbelessthanitr2Label.Text = 'Refresh rate too small.It must be less than itr/2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demoapp2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end